classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup2_2                     matlab.ui.container.TabGroup
        Tab_2                           matlab.ui.container.Tab
        UITable_2                       matlab.ui.control.Table
        Tab2_2                          matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        Tab2                            matlab.ui.container.Tab
        Button2                         matlab.ui.control.Button
        FunctionModelLabel              matlab.ui.control.Label
        Tree                            matlab.ui.container.CheckBoxTree
        Node                            matlab.ui.container.TreeNode
        Node2                           matlab.ui.container.TreeNode
        Node3                           matlab.ui.container.TreeNode
        Node4                           matlab.ui.container.TreeNode
        TabGroup                        matlab.ui.container.TabGroup
        PredefinedTab                   matlab.ui.container.Tab
        FDropDown                       matlab.ui.control.DropDown
        FDropDownLabel                  matlab.ui.control.Label
        CustomizedTab                   matlab.ui.container.Tab
        TextArea2                       matlab.ui.control.TextArea
        TextArea2Label                  matlab.ui.control.Label
        AdjustmentProblemDropDown       matlab.ui.control.DropDown
        AdjustmentProblemDropDownLabel  matlab.ui.control.Label
        VCECheckBox                     matlab.ui.control.CheckBox
        TextArea                        matlab.ui.control.TextArea
        TextAreaLabel                   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Read table array from file
            t = readtable('patients.xls');
            vars = {'Age','Systolic','Diastolic','SelfAssessedHealthStatus','Smoker'};
            
            % Select a subset of the table array
            t = t(1:20,vars);
            
            % Sort the data by age
            t = sortrows(t,'Age');
            
            % Combine Systolic and Diastolic into one variable
            t.BloodPressure = [t.Systolic t.Diastolic];
            t.Systolic = [];
            t.Diastolic = [];
            
            % Convert SelfAssessedHealthStatus to categorical
            cats = categorical(t.SelfAssessedHealthStatus,{'Poor','Fair','Good','Excellent'});
            t.SelfAssessedHealthStatus = cats;
            
            % Rearrange columns
            t = t(:,[1 4 3 2]);
            
            % Add data to the Table UI Component
            app.UITable.Data = t;


        end

        % Callback function
        function L_input(app, event)
            value = app.Button.Value;
            [file,path] = uigetfile('*.m');
            if isequal(file,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path,file)]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.VCECheckBox = uicheckbox(app.UIFigure);
            app.VCECheckBox.Text = 'VCE';
            app.AdjustmentProblemDropDownLabel = uilabel(app.UIFigure);
            app.AdjustmentProblemDropDownLabel.Text = 'Adjustment Problem';
            app.AdjustmentProblemDropDown = uidropdown(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PredefinedTab = uitab(app.TabGroup);
            app.PredefinedTab.Title = 'Predefined';
            app.FDropDownLabel = uilabel(app.PredefinedTab);
            app.FDropDownLabel.Text = 'F';
            app.FDropDown = uidropdown(app.PredefinedTab);
            app.CustomizedTab = uitab(app.TabGroup);
            app.CustomizedTab.Title = 'Customized';
            app.TextArea2Label = uilabel(app.CustomizedTab);
            app.TextArea2Label.Text = 'Text Area2';
            app.TextArea2 = uitextarea(app.CustomizedTab);
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.FunctionModelLabel = uilabel(app.UIFigure);
            app.FunctionModelLabel.Text = 'Function Model';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.Text = 'Button2';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = 'Tab';
            app.UITable = uitable(app.Tab);
            app.UITable.ColumnName = {'Symbol'; 'Value'};
            app.UITable.RowName = {'Column 1,Column 2'};
            app.Tab2 = uitab(app.TabGroup2);
            app.Tab2.Title = 'Tab2';
            app.TabGroup2_2 = uitabgroup(app.UIFigure);
            app.Tab_2 = uitab(app.TabGroup2_2);
            app.Tab_2.Title = 'Tab';
            app.UITable_2 = uitable(app.Tab_2);
            app.UITable_2.ColumnName = {'Symbol'; 'Value'; 'Symsbol'; 'Valsue'};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnSortable = [true false true true];
            app.UITable_2.ColumnEditable = [true false true true];
            app.Tab2_2 = uitab(app.TabGroup2_2);
            app.Tab2_2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end